classdef ptycho_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        OutputpathEditField           matlab.ui.control.EditField
        OutputpathEditFieldLabel      matlab.ui.control.Label
        selectButton_3                matlab.ui.control.Button
        CustomizeoutputpathCheckBox   matlab.ui.control.CheckBox
        CroprealspaceCheckBox         matlab.ui.control.CheckBox
        ScanRotSpinner                matlab.ui.control.Spinner
        ScanRotSpinnerLabel           matlab.ui.control.Label
        ApplytoallButton_2            matlab.ui.control.Button
        ApplytoallButton              matlab.ui.control.Button
        ShowHyperParamButton          matlab.ui.control.Button
        DeleteRowButton_3             matlab.ui.control.Button
        AddRowButton_3                matlab.ui.control.Button
        DeleteRowButton               matlab.ui.control.Button
        AddRowButton                  matlab.ui.control.Button
        Ptycho_GUIWrittenbyZehaoDongTHU2025Label  matlab.ui.control.Label
        ReconstructButton             matlab.ui.control.Button
        PtychoreconstructionLabel     matlab.ui.control.Label
        RotateSwitch                  matlab.ui.control.Switch
        Np_padtoSpinner               matlab.ui.control.Spinner
        Np_padtoSpinnerLabel          matlab.ui.control.Label
        Np_bintoSpinner               matlab.ui.control.Spinner
        Np_bintoSpinnerLabel          matlab.ui.control.Label
        selectButton_2                matlab.ui.control.Button
        selectButton                  matlab.ui.control.Button
        DfolderpatternEditField       matlab.ui.control.EditField
        DfolderpatternEditFieldLabel  matlab.ui.control.Label
        FilenameEditField             matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        PrepareButton                 matlab.ui.control.Button
        PreparedataLabel              matlab.ui.control.Label
        TestButton                    matlab.ui.control.Button
        TestpathandcameraLabel        matlab.ui.control.Label
        Np_presolveSpinner            matlab.ui.control.Spinner
        Np_presolveSpinnerLabel       matlab.ui.control.Label
        gpu_idSpinner                 matlab.ui.control.Spinner
        gpu_idSpinnerLabel            matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        DatapathEditField             matlab.ui.control.EditField
        DatapathEditFieldLabel        matlab.ui.control.Label
        PtychopathEditField           matlab.ui.control.EditField
        PtychopathEditFieldLabel      matlab.ui.control.Label
        DETECTORDropDown              matlab.ui.control.DropDown
        DETECTORDropDownLabel         matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        STOPButton                    matlab.ui.control.Button
        LayertoplotSpinner            matlab.ui.control.Spinner
        LayertoplotSpinnerLabel       matlab.ui.control.Label
        HyperparametersnotoftenusedPanel  matlab.ui.container.Panel
        SavedpCheckBox                matlab.ui.control.CheckBox
        ADUEditField                  matlab.ui.control.NumericEditField
        ADUEditFieldLabel             matlab.ui.control.Label
        RotoffsetEditField            matlab.ui.control.NumericEditField
        RotoffsetEditFieldLabel       matlab.ui.control.Label
        rbfEditField                  matlab.ui.control.NumericEditField
        rbfEditFieldLabel             matlab.ui.control.Label
        StepsizeEditField             matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel        matlab.ui.control.Label
        AccVoltEditField              matlab.ui.control.NumericEditField
        AccVoltEditFieldLabel         matlab.ui.control.Label
        alphaEditField                matlab.ui.control.NumericEditField
        alphaEditFieldLabel           matlab.ui.control.Label
        UITable_3                     matlab.ui.control.Table
        removeambiguitySwitch         matlab.ui.control.Switch
        removeambiguitySwitchLabel    matlab.ui.control.Label
        reg_layerEditField            matlab.ui.control.NumericEditField
        reg_layerEditFieldLabel       matlab.ui.control.Label
        NiterplotSpinner              matlab.ui.control.Spinner
        NiterplotSpinnerLabel         matlab.ui.control.Label
        NitersaveSpinner              matlab.ui.control.Spinner
        NitersaveSpinnerLabel         matlab.ui.control.Label
        NiterposcorrSpinner           matlab.ui.control.Spinner
        NiterposcorrSpinnerLabel      matlab.ui.control.Label
        NiterprobeupdSpinner          matlab.ui.control.Spinner
        NiterprobeupdSpinnerLabel     matlab.ui.control.Label
        ProbemodeSpinner              matlab.ui.control.Spinner
        ProbemodeSpinnerLabel         matlab.ui.control.Label
        blurPSFEditField              matlab.ui.control.NumericEditField
        blurPSFEditFieldLabel         matlab.ui.control.Label
        LogoutputTextArea             matlab.ui.control.TextArea
        LogoutputTextAreaLabel        matlab.ui.control.Label
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        object_temp                   cell                % save intermediate object to plot
        cache_temp                    struct = struct();  % save cache params for plotting
        StopRequested                       = false;     % whether stop running is requested
    end
    
    methods (Access = private)
        
        function DeleteSelectedRowButtonPushed(app, event)
            selectedRow = app.UITable.Selection(1);  % 获取选中的行索引
            if ~isempty(selectedRow)
                app.UITable.Data(selectedRow, :) = []; % 删除选定行
            end
        end
        
        function FilenameChanged(app)
            [~, ~, ext] = fileparts(app.FilenameEditField.Value);
            switch ext
                case '.dm4'
                    app.ScanRotSpinner.Visible = "off";
                    app.ScanRotSpinnerLabel.Visible = "off";
                    utils.LogMessage(app, 'scan rotation imported from .dm4 file automatically.')
                otherwise
                    app.ScanRotSpinner.Visible = "on";
                    app.ScanRotSpinnerLabel.Visible = "on";
                    utils.LogMessage(app, 'scan rotation manually determined.')
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            [appPath, ~, ~] = fileparts(mfilename('fullpath'));
            app.PtychopathEditField.Value = appPath;

            % 获取 +config 目录下所有 .mat 文件
            matFiles = dir(fullfile(app.PtychopathEditField.Value, '+config', '*.mat'));
            fileNames = {matFiles.name};
            fileNames = erase(fileNames, '.mat');
            if ~isempty(fileNames)
                app.DETECTORDropDown.Items = fileNames;
            else
                app.DETECTORDropDown.Items = {'No files found'};
            end

            % Disable stop button
            app.STOPButton.Enable = 'off';
        end

        % Button pushed function: selectButton
        function selectButtonPushed(app, event)
            app.PtychopathEditField.Value = uigetdir();
            figure(app.UIFigure);
            % 获取 +config 目录下所有 .mat 文件
            matFiles = dir(fullfile(app.PtychopathEditField.Value, '+config', '*.mat'));
            fileNames = {matFiles.name};
            fileNames = erase(fileNames, '.mat');
            if ~isempty(fileNames)
                app.DETECTORDropDown.Items = fileNames;
            else
                app.DETECTORDropDown.Items = {'No files found'};
            end
        end

        % Button pushed function: selectButton_2
        function selectButton_2Pushed(app, event)
            app.DatapathEditField.Value = uigetdir();
            figure(app.UIFigure);
        end

        % Value changed function: DETECTORDropDown
        function DETECTORDropDownValueChanged(app, event)
            selectedFile = app.DETECTORDropDown.Value;
            % 检查是否为有效文件
            if strcmp(selectedFile, 'No files found')
                utils.LogMessage(app, sprintf('No files found'))
                return; % 不加载
            end
            % 构造完整文件路径
            configPath = fullfile(app.PtychopathEditField.Value, '+config', [selectedFile, '.mat']);
            
            % 加载 .mat 文件
            if isfile(configPath)
                configs = load(configPath);
                app.AccVoltEditField.Value = configs.voltage;
                app.alphaEditField.Value = configs.alpha;
                app.rbfEditField.Value = configs.rbf;
                app.StepsizeEditField.Value = configs.stepsize;
                app.RotoffsetEditField.Value = configs.rotation;
                app.ADUEditField.Value = configs.ADU;
                app.DfolderpatternEditField.Value = configs.folderpattern;
                app.FilenameEditField.Value = configs.filename;
                FilenameChanged(app);
            else
                uialert(app.UIFigure, 'File not found!', 'Error');
            end

        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.LogoutputTextArea.Value = '';
            drawnow;
            cd(app.PtychopathEditField.Value);
            init_dir = fullfile(app.DatapathEditField.Value, app.DfolderpatternEditField.Value);
            filename = app.FilenameEditField.Value;
            fileList = dir(fullfile(init_dir, filename));
            Table1 = zeros(size(fileList,1), 6);
            Table2 = zeros(size(fileList,1), size(app.UITable_2.ColumnName,1));
            for index = 1:size(fileList,1)
                % read data
                % determine output data path
                isCustomOutputPath = app.CustomizeoutputpathCheckBox.Value;
                if ~isCustomOutputPath
                    data_dir = fullfile(app.DatapathEditField.Value,'All_Data');
                else
                    data_dir = app.OutputpathEditField.Value;
                end
                if ~exist(data_dir)
                    mkdir(data_dir)
                end 
                [~, final_folder] = fileparts(fileList(index).folder);
                utils.LogMessage(app, sprintf('Scan number %02d: %s', index, fullfile(final_folder, fileList(index).name)));
                Table1(index, 1:6) = [index, 240, 24, 1000, 400, 1];
                Table2(index, 1:2) = [index, -150];
                if app.CroprealspaceCheckBox.Value
                    Table2(index, end-3:end) = [1 100 1 100];
                end
            end
            app.UITable.Data = Table1;
            app.UITable_2.Data = Table2;
            app.UITable_2.ColumnEditable = repmat([true], [1,size(Table2,2)]);
        end

        % Button pushed function: PrepareButton
        function PrepareButtonPushed(app, event)
            app.LogoutputTextArea.Value = '';
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes_2, 'reset');
            cla(app.UIAxes_3, 'reset');
            app.PrepareButton.Enable = "off";
            app.STOPButton.Enable = "on";
            app.StopRequested = false;
            drawnow;
            try
                utils.OnPrepare(app);
            catch ME
                errorMsg = getReport(ME, 'extended', 'hyperlinks', 'off');
                utils.LogMessage(app, errorMsg);
            end
            app.PrepareButton.Enable = "on";
            app.STOPButton.Enable = "off";
            app.StopRequested = false;
            utils.LogMessage(app, '====== Preparation Finished ======');
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            currentData = app.UITable_2.Data;
            if size(currentData, 1) == 0
                newRow = ones(1,size(app.UITable_2.ColumnName,1));
                if app.CroprealspaceCheckBox.Value
                    newRow(end-3:end) = [1 100 1 100]; 
                end
                newRow(1:2) = [1, -150];
            else
                newRow = currentData(end, :); 
                newRow(1) = newRow(1) + 1;
            end
            app.UITable_2.Data = [currentData; newRow];
        end

        % Button pushed function: AddRowButton_3
        function AddRowButton_3Pushed(app, event)
            currentData = app.UITable.Data;
            if size(currentData, 1) == 0
                newRow = ones(1,size(app.UITable.ColumnName,1));
                newRow(1:6) = [1, 240, 24, 1000, 400, 1];
            else
                newRow = currentData(end, :); 
                newRow(1) = newRow(1) + 1;
            end
            app.UITable.Data = [currentData; newRow];
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            try
                selectedRow = app.UITable_2.Selection(1); 
                if ~isempty(selectedRow)
                    app.UITable_2.Data(selectedRow, :) = [];
                end
            catch
                utils.LogMessage(app, 'Empty row, cannot delete!')
            end
        end

        % Button pushed function: DeleteRowButton_3
        function DeleteRowButton_3Pushed(app, event)
            try
                selectedRow = app.UITable.Selection(1); 
                if ~isempty(selectedRow)
                    app.UITable.Data(selectedRow, :) = [];
                end
            catch
                utils.LogMessage(app, 'Empty row, cannot delete!')
            end
        end

        % Callback function
        function ENDButtonPushed(app, event)
            % 1. 终止定时器或后台进程
            if isfield(app, 'MyTimer') && isvalid(app.MyTimer)
                stop(app.MyTimer);
                delete(app.MyTimer);
                app.MyTimer = [];
            end
        
            % 2. 终止任何可能的运行进程
            drawnow; % 确保 UI 线程可以执行
            if ~isempty(dbstack) % 检查是否有活动的回调函数
                return; % 终止当前函数的执行
            end
        
            % 3. 关闭等待状态 (如 uiwait, pause, 等)
            try
                uiresume(app.UIFigure); % 如果存在 uiwait，恢复 UI
            catch
            end
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            app.LogoutputTextArea.Value = '';
            drawnow;
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes_2, 'reset');
            cla(app.UIAxes_3, 'reset');
            app.ReconstructButton.Enable = "off";
            app.STOPButton.Enable = "on";
            app.StopRequested = false;
            try
                utils.OnReconstruct(app);
            catch ME
                utils.LogMessage(app, 'Reconstruction terminated.');
            end
            app.ReconstructButton.Enable = "on";
            app.STOPButton.Enable = "off";
            app.StopRequested = false;
            utils.LogMessage(app, '====== Reconstruction Finished ======');
        end

        % Button pushed function: ShowHyperParamButton
        function ShowHyperParamButtonPushed(app, event)
            if app.HyperparametersnotoftenusedPanel.Visible  % 如果面板可见
                app.HyperparametersnotoftenusedPanel.Visible = 'off';  % 隐藏面板
                app.ShowHyperParamButton.Text = 'Show Hyper Param.'; % 更改按钮文本
                drawnow;
            else
                app.HyperparametersnotoftenusedPanel.Visible = 'on';  % 显示面板
                app.ShowHyperParamButton.Text = 'Hide Hyper Param.'; % 更改按钮文本
                drawnow;
            end
        end

        % Button pushed function: ApplytoallButton
        function ApplytoallButtonPushed(app, event)
            currentData = app.UITable_2.Data;
            if size(currentData, 1) == 0
                return
            else
                currentData(:,2:end) = repmat(currentData(1,2:end), [size(currentData,1) 1]);
            end
            app.UITable_2.Data = currentData;
        end

        % Button pushed function: ApplytoallButton_2
        function ApplytoallButton_2Pushed(app, event)
            currentData = app.UITable.Data;
            if size(currentData, 1) == 0
                return
            else
                currentData(:,2:end) = repmat(currentData(1,2:end), [size(currentData,1) 1]);
            end
            app.UITable.Data = currentData;
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            FilenameChanged(app);
        end

        % Value changed function: CroprealspaceCheckBox
        function CroprealspaceCheckBoxValueChanged(app, event)
            iscrop = app.CroprealspaceCheckBox.Value;
            currentData = app.UITable_2.Data;
            if iscrop
                if size(currentData,1) > 0
                    newColumn = repmat([1 100 1 100], [size(currentData, 1), 1]);
                    app.UITable_2.Data = [currentData, newColumn];
                end
                app.UITable_2.ColumnName = [app.UITable_2.ColumnName;{'x0'; 'x1'; 'y0'; 'y1'}];
                app.UITable_2.ColumnEditable = true;
            else
                if size(currentData,1) > 0
                    app.UITable_2.Data = app.UITable_2.Data(:,1:end-4);
                end
                app.UITable_2.ColumnName = {app.UITable_2.ColumnName{1:end-4}}';
            end
        end

        % Value changed function: CustomizeoutputpathCheckBox
        function CustomizeoutputpathCheckBoxValueChanged(app, event)
            isCustomOutputPath = app.CustomizeoutputpathCheckBox.Value;
            if ~isCustomOutputPath
                app.OutputpathEditField.Visible = "off";
                app.OutputpathEditFieldLabel.Visible = "off";
                app.selectButton_3.Visible = "off";
                utils.LogMessage(app, sprintf('Use %s as output path.', fullfile(app.DatapathEditField.Value, 'All_Data')))
            else
                app.OutputpathEditField.Visible = "on";
                app.OutputpathEditFieldLabel.Visible = "on";
                app.selectButton_3.Visible = "on";
                utils.LogMessage(app, 'Use custom path as output path.')
            end
        end

        % Button pushed function: selectButton_3
        function selectButton_3Pushed(app, event)
            app.OutputpathEditField.Value = uigetdir();
            utils.LogMessage(app, sprintf('Use %s as output path.', fullfile(app.DatapathEditField.Value, app.OutputpathEditField.Value)))
            figure(app.UIFigure);
        end

        % Value changed function: LayertoplotSpinner
        function LayertoplotSpinnerValueChanged(app, event)
            value = app.LayertoplotSpinner.Value;
            if isempty(app.object_temp)
                return
            elseif value < 1
                app.LayertoplotSpinner.Value = 1;
                utils.LogMessage(app, 'Layer smaller than expected')
                return
            elseif value > length(app.object_temp)
                app.LayertoplotSpinner.Value = length(app.object_temp);
                utils.LogMessage(app, 'Layer larger than expected')
                return
            else
                utils.plot_object(app, app.object_temp, app.cache_temp);
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.StopRequested = true;
            utils.LogMessage(app, 'Stop request received ......');
            app.STOPButton.Enable = "off";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {778, 778};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {638, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DETECTORDropDownLabel = uilabel(app.LeftPanel);
            app.DETECTORDropDownLabel.Text = 'DETECTOR';
            app.DETECTORDropDown = uidropdown(app.LeftPanel);
            app.DETECTORDropDown.Items = {'K3_ARM300'};
            app.DETECTORDropDown.ValueChangedFcn = createCallbackFcn(app, @DETECTORDropDownValueChanged, true);
            app.DETECTORDropDown.Value = 'K3_ARM300';
            app.PtychopathEditFieldLabel = uilabel(app.LeftPanel);
            app.PtychopathEditFieldLabel.Text = 'Ptycho path';
            app.PtychopathEditField = uieditfield(app.LeftPanel, 'text');
            app.DatapathEditFieldLabel = uilabel(app.LeftPanel);
            app.DatapathEditFieldLabel.Text = 'Data path';
            app.DatapathEditField = uieditfield(app.LeftPanel, 'text');
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Scan number'; 'Total thickness'; 'N_layers'; 'N_iterations'; 'grouping'; 'beta_LSQ'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.gpu_idSpinnerLabel = uilabel(app.LeftPanel);
            app.gpu_idSpinnerLabel.Text = 'gpu_id';
            app.gpu_idSpinner = uispinner(app.LeftPanel);
            app.gpu_idSpinner.Value = 1;
            app.Np_presolveSpinnerLabel = uilabel(app.LeftPanel);
            app.Np_presolveSpinnerLabel.Text = 'Np_presolve';
            app.Np_presolveSpinner = uispinner(app.LeftPanel);
            app.Np_presolveSpinner.Value = 128;
            app.TestpathandcameraLabel = uilabel(app.LeftPanel);
            app.TestpathandcameraLabel.Text = {'1. Test path '; 'and camera'};
            app.TestButton = uibutton(app.LeftPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.PreparedataLabel = uilabel(app.LeftPanel);
            app.PreparedataLabel.Text = '2. Prepare data';
            app.PrepareButton = uibutton(app.LeftPanel, 'push');
            app.PrepareButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareButtonPushed, true);
            app.PrepareButton.Text = 'Prepare';
            app.UITable_2 = uitable(app.LeftPanel);
            app.UITable_2.ColumnName = {'Scan No.'; 'df'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = true;
            app.FilenameEditFieldLabel = uilabel(app.LeftPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.LeftPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FilenameEditField.Value = 'STEM SI.dm4';
            app.DfolderpatternEditFieldLabel = uilabel(app.LeftPanel);
            app.DfolderpatternEditFieldLabel.Text = '4D folder pattern';
            app.DfolderpatternEditField = uieditfield(app.LeftPanel, 'text');
            app.DfolderpatternEditField.Value = '4D region*';
            app.selectButton = uibutton(app.LeftPanel, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton.Text = 'select';
            app.selectButton_2 = uibutton(app.LeftPanel, 'push');
            app.selectButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectButton_2Pushed, true);
            app.selectButton_2.Text = 'select';
            app.Np_bintoSpinnerLabel = uilabel(app.LeftPanel);
            app.Np_bintoSpinnerLabel.Text = 'Np_binto';
            app.Np_bintoSpinner = uispinner(app.LeftPanel);
            app.Np_bintoSpinner.Value = 128;
            app.Np_padtoSpinnerLabel = uilabel(app.LeftPanel);
            app.Np_padtoSpinnerLabel.Text = 'Np_padto';
            app.Np_padtoSpinner = uispinner(app.LeftPanel);
            app.Np_padtoSpinner.Value = 190;
            app.RotateSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RotateSwitch.Items = {'Rot. Scan', 'Rot. CBED'};
            app.RotateSwitch.Value = 'Rot. CBED';
            app.PtychoreconstructionLabel = uilabel(app.LeftPanel);
            app.PtychoreconstructionLabel.Text = '3. Ptycho reconstruction';
            app.ReconstructButton = uibutton(app.LeftPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.Ptycho_GUIWrittenbyZehaoDongTHU2025Label = uilabel(app.LeftPanel);
            app.Ptycho_GUIWrittenbyZehaoDongTHU2025Label.Text = 'Ptycho_GUI, Written by Zehao Dong @ THU, 2025';
            app.AddRowButton = uibutton(app.LeftPanel, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.DeleteRowButton = uibutton(app.LeftPanel, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = 'Delete Row';
            app.AddRowButton_3 = uibutton(app.LeftPanel, 'push');
            app.AddRowButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddRowButton_3Pushed, true);
            app.AddRowButton_3.Text = 'Add Row';
            app.DeleteRowButton_3 = uibutton(app.LeftPanel, 'push');
            app.DeleteRowButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButton_3Pushed, true);
            app.DeleteRowButton_3.Text = 'Delete Row';
            app.ShowHyperParamButton = uibutton(app.LeftPanel, 'push');
            app.ShowHyperParamButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHyperParamButtonPushed, true);
            app.ShowHyperParamButton.Text = 'Show Hyper Param.';
            app.ApplytoallButton = uibutton(app.LeftPanel, 'push');
            app.ApplytoallButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoallButtonPushed, true);
            app.ApplytoallButton.Text = 'Apply to all';
            app.ApplytoallButton_2 = uibutton(app.LeftPanel, 'push');
            app.ApplytoallButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplytoallButton_2Pushed, true);
            app.ApplytoallButton_2.Text = 'Apply to all';
            app.ScanRotSpinnerLabel = uilabel(app.LeftPanel);
            app.ScanRotSpinnerLabel.Visible = 'off';
            app.ScanRotSpinnerLabel.Text = 'Scan Rot.';
            app.ScanRotSpinner = uispinner(app.LeftPanel);
            app.ScanRotSpinner.Visible = 'off';
            app.CroprealspaceCheckBox = uicheckbox(app.LeftPanel);
            app.CroprealspaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @CroprealspaceCheckBoxValueChanged, true);
            app.CroprealspaceCheckBox.Text = 'Crop real space';
            app.CustomizeoutputpathCheckBox = uicheckbox(app.LeftPanel);
            app.CustomizeoutputpathCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomizeoutputpathCheckBoxValueChanged, true);
            app.CustomizeoutputpathCheckBox.Text = 'Customize output path';
            app.selectButton_3 = uibutton(app.LeftPanel, 'push');
            app.selectButton_3.ButtonPushedFcn = createCallbackFcn(app, @selectButton_3Pushed, true);
            app.selectButton_3.Visible = 'off';
            app.selectButton_3.Text = 'select';
            app.OutputpathEditFieldLabel = uilabel(app.LeftPanel);
            app.OutputpathEditFieldLabel.Visible = 'off';
            app.OutputpathEditFieldLabel.Text = 'Output path';
            app.OutputpathEditField = uieditfield(app.LeftPanel, 'text');
            app.OutputpathEditField.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.LogoutputTextAreaLabel = uilabel(app.RightPanel);
            app.LogoutputTextAreaLabel.Text = 'Log output';
            app.LogoutputTextArea = uitextarea(app.RightPanel);
            app.HyperparametersnotoftenusedPanel = uipanel(app.RightPanel);
            app.HyperparametersnotoftenusedPanel.Title = 'Hyper-parameters not often used';
            app.HyperparametersnotoftenusedPanel.Visible = 'off';
            app.blurPSFEditFieldLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.blurPSFEditFieldLabel.Text = 'blur (PSF)';
            app.blurPSFEditField = uieditfield(app.HyperparametersnotoftenusedPanel, 'numeric');
            app.blurPSFEditField.Value = 1.5;
            app.ProbemodeSpinnerLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.ProbemodeSpinnerLabel.Text = 'Probe mode';
            app.ProbemodeSpinner = uispinner(app.HyperparametersnotoftenusedPanel);
            app.ProbemodeSpinner.Value = 2;
            app.NiterprobeupdSpinnerLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.NiterprobeupdSpinnerLabel.Text = 'Niter probe upd.';
            app.NiterprobeupdSpinner = uispinner(app.HyperparametersnotoftenusedPanel);
            app.NiterprobeupdSpinner.Value = 10;
            app.NiterposcorrSpinnerLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.NiterposcorrSpinnerLabel.Text = 'Niter pos. corr.';
            app.NiterposcorrSpinner = uispinner(app.HyperparametersnotoftenusedPanel);
            app.NiterposcorrSpinner.Value = 20;
            app.NitersaveSpinnerLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.NitersaveSpinnerLabel.Text = 'Niter save ';
            app.NitersaveSpinner = uispinner(app.HyperparametersnotoftenusedPanel);
            app.NitersaveSpinner.Value = 200;
            app.NiterplotSpinnerLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.NiterplotSpinnerLabel.Text = 'Niter plot ';
            app.NiterplotSpinner = uispinner(app.HyperparametersnotoftenusedPanel);
            app.NiterplotSpinner.Value = 10;
            app.reg_layerEditFieldLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.reg_layerEditFieldLabel.Text = 'reg_layer';
            app.reg_layerEditField = uieditfield(app.HyperparametersnotoftenusedPanel, 'numeric');
            app.reg_layerEditField.Value = 0.3;
            app.removeambiguitySwitchLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.removeambiguitySwitchLabel.Text = 'remove ambiguity?';
            app.removeambiguitySwitch = uiswitch(app.HyperparametersnotoftenusedPanel, 'slider');
            app.removeambiguitySwitch.Items = {'No', 'Yes'};
            app.removeambiguitySwitch.Value = 'Yes';
            app.UITable_3 = uitable(app.HyperparametersnotoftenusedPanel);
            app.UITable_3.ColumnName = {'No.'; 'Crop_x0'; 'x1'; 'y0'; 'y1'; 'Scan rot.'};
            app.UITable_3.RowName = {};
            app.alphaEditFieldLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.HyperparametersnotoftenusedPanel, 'numeric');
            app.alphaEditField.Value = 24.3;
            app.AccVoltEditFieldLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.AccVoltEditFieldLabel.Text = 'Acc. Volt.';
            app.AccVoltEditField = uieditfield(app.HyperparametersnotoftenusedPanel, 'numeric');
            app.AccVoltEditField.Value = 300;
            app.StepsizeEditFieldLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.HyperparametersnotoftenusedPanel, 'numeric');
            app.StepsizeEditField.Value = 0.524;
            app.rbfEditFieldLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.rbfEditFieldLabel.Text = 'rbf';
            app.rbfEditField = uieditfield(app.HyperparametersnotoftenusedPanel, 'numeric');
            app.rbfEditField.Value = 79.26;
            app.RotoffsetEditFieldLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.RotoffsetEditFieldLabel.Text = 'Rot. offset';
            app.RotoffsetEditField = uieditfield(app.HyperparametersnotoftenusedPanel, 'numeric');
            app.RotoffsetEditField.Value = 50.5;
            app.ADUEditFieldLabel = uilabel(app.HyperparametersnotoftenusedPanel);
            app.ADUEditFieldLabel.Text = 'ADU';
            app.ADUEditField = uieditfield(app.HyperparametersnotoftenusedPanel, 'numeric');
            app.ADUEditField.Value = 1;
            app.SavedpCheckBox = uicheckbox(app.HyperparametersnotoftenusedPanel);
            app.SavedpCheckBox.Text = 'Save dp?';
            app.SavedpCheckBox.Value = true;
            app.LayertoplotSpinnerLabel = uilabel(app.RightPanel);
            app.LayertoplotSpinnerLabel.Text = 'Layer to plot';
            app.LayertoplotSpinner = uispinner(app.RightPanel);
            app.LayertoplotSpinner.ValueChangedFcn = createCallbackFcn(app, @LayertoplotSpinnerValueChanged, true);
            app.STOPButton = uibutton(app.RightPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ptycho_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end